classdef electronic_load_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
        StartSwitch  matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            arduino = serialport("COM5", 9600)
            tic
            time = toc;
            while time < 25 
                s.value = app.StartSwitch.Value
                txt = jsonencode(s)
                txt = string(txt)+'\n';
                writeline(arduino, txt)
                getpinstatus(arduino)
                %readline(arduino)
                time = toc
                pause(0.1);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartSwitch = uiswitch(app.UIFigure, 'slider');
            app.StartSwitch.Items = {'0', '1'};
            app.StartSwitch.Value = '0';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electronic_load_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end