classdef electronic_load_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SetupTab               matlab.ui.container.Tab
        SetParametersButton    matlab.ui.control.Button
        COMPortEditField       matlab.ui.control.NumericEditField
        COMPortEditFieldLabel  matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        CurrentASlider         matlab.ui.control.Slider
        CurrentASliderLabel    matlab.ui.control.Label
        PowerWSlider           matlab.ui.control.Slider
        PowerWSliderLabel      matlab.ui.control.Label
        ModeofOperationSwitch  matlab.ui.control.Switch
        ReadingsTab            matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            port = "COM" + app.COMPortEditField.Value
            arduino = serialport(port, 9600)
            s.power = round(app.PowerWSlider.Value, 4, "significant")
            s.current = round(app.CurrentASlider.Value, 4, "significant")
            arduino.Terminator

            % Turn a lamp on when the button is pushed so the user has
            % feedback for their action
            app.Lamp.Color = [0, 1, 0];
            pause(0.5)
            app.Lamp.Color = [0.65,0.65,0.65];

            if  string(app.ModeofOperationSwitch.Value) == 'Power'
                s.mode = '1';
            else 
                s.mode = '0';
            end
            
            txt = jsonencode(s)
            writeline(arduino, txt);
            
            %Clear all previous data from the serial port
            flush(arduino);
            
            %Read the data from the arduino(must be terminated by newline
            %character BY ARDUINO)
            output = readline(arduino) %This will read "Initial Message"
            while string(output) == "Initial Message"
                writeline(arduino,txt)
                output = readline(arduino)
            end

            %Decode the data into a cell array so that we can print it in
            %the readings table. Make sure to tranpose(') otherwise the
            %data will not be formatted properly for the table.
            readings = struct2cell(jsondecode(output))'

            %Print the data read from arduino onto the table
            app.UITable.Data = readings

            clear arduino
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ModeofOperationSwitch = uiswitch(app.SetupTab, 'slider');
            app.ModeofOperationSwitch.Items = {'Power', 'Current'};
            app.ModeofOperationSwitch.Value = 'Power';
            app.PowerWSliderLabel = uilabel(app.SetupTab);
            app.PowerWSliderLabel.Text = 'Power(W)';
            app.PowerWSlider = uislider(app.SetupTab);
            app.PowerWSlider.Limits = [0 80];
            app.CurrentASliderLabel = uilabel(app.SetupTab);
            app.CurrentASliderLabel.Text = 'Current(A)';
            app.CurrentASlider = uislider(app.SetupTab);
            app.CurrentASlider.Limits = [0 5];
            app.Lamp = uilamp(app.SetupTab);
            app.COMPortEditFieldLabel = uilabel(app.SetupTab);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.SetupTab, 'numeric');
            app.COMPortEditField.Value = 5;
            app.SetParametersButton = uibutton(app.SetupTab, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.ReadingsTab = uitab(app.TabGroup);
            app.ReadingsTab.Title = 'Readings';
            app.UITable = uitable(app.ReadingsTab);
            app.UITable.ColumnName = {'Power(W)'; 'Current(A)'; 'Voltage(V)'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electronic_load_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end